classdef surface2volume < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Surface2volumealgorithmUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        S2VTab                        matlab.ui.container.Tab
        Button                        matlab.ui.control.Button
        reset                         matlab.ui.control.Button
        Backward                      matlab.ui.control.Button
        Forward                       matlab.ui.control.Button
        Play                          matlab.ui.control.Button
        Surface2volumesettingsPanel   matlab.ui.container.Panel
        ThicknessEditField            matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel       matlab.ui.control.Label
        AnglethresholdEditField       matlab.ui.control.NumericEditField
        AnglethresholdEditFieldLabel  matlab.ui.control.Label
        PlottingTab                   matlab.ui.container.Tab
        SettingsforplottingPanel      matlab.ui.container.Panel
        AutoscaleCheckBox             matlab.ui.control.CheckBox
        QuiverstepEditField           matlab.ui.control.NumericEditField
        QuiverstepEditFieldLabel      matlab.ui.control.Label
        QuiverscaleEditField          matlab.ui.control.NumericEditField
        QuiverscaleEditFieldLabel     matlab.ui.control.Label
        AlphavalueEditField           matlab.ui.control.NumericEditField
        PlotColorFunCheckBox          matlab.ui.control.CheckBox
        ColorfunctionEditField        matlab.ui.control.EditField
        AlphavalueSlider              matlab.ui.control.Slider
        AlphavalueSliderLabel         matlab.ui.control.Label
        coarseLinearSamplingCheckBox  matlab.ui.control.CheckBox
        ShowobjectCheckBox            matlab.ui.control.CheckBox
        ShownormalvectorsCheckBox     matlab.ui.control.CheckBox
        ShowparametricdirectionsCheckBox  matlab.ui.control.CheckBox
        ShowelementedgesCheckBox      matlab.ui.control.CheckBox
        ShowcontrolpolygonCheckBox    matlab.ui.control.CheckBox
        PlottingresolutionEditField   matlab.ui.control.NumericEditField
        PlottingresolutionEditFieldLabel  matlab.ui.control.Label
        CamlightCheckBox              matlab.ui.control.CheckBox
        PostprocessingTab             matlab.ui.container.Tab
        RefinementPanel               matlab.ui.container.Panel
        Seth_maxEditField             matlab.ui.control.NumericEditField
        Seth_maxEditFieldLabel        matlab.ui.control.Label
        RefineButton                  matlab.ui.control.Button
        andLabel                      matlab.ui.control.Label
        ExportPanel                   matlab.ui.container.Panel
        DropDown                      matlab.ui.control.DropDown
        Exportg2fileButton            matlab.ui.control.Button
        ORLabel                       matlab.ui.control.Label
        ModelDropDown                 matlab.ui.control.DropDown
        Loadg2fileButton              matlab.ui.control.Button
        Surface2volumealgorithmLabel  matlab.ui.control.Label
        ModelDropDownLabel            matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        PopoutButton                  matlab.ui.control.Button
        ReadyLamp                     matlab.ui.control.Lamp
        ReadyLampLabel                matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        thickness   % Thickness of patches to be extruded
        S2Vobj   % Object containing current state of the S2V algorithm
        nurbs_vol_connection
        nurbs_vol_refined   % resulting refined nurbs volume
        sharpAngle 
        camlightHandle
        objectHandle
        elementEdgesHandle
        controlPolygonHandle
        normalVectorsHandle
        parmDirHandle
        plotControlPolygon
        plotElementEdges
        plotObject
        plotColor
        options_default
        pauseAutomation
        colors % Colors used for volumetric meshing
        no_colors % Colors used for volumetric meshing
        Eps % Geometric tolerance matching
    end
    
    methods (Access = private)
               
        function plot_nurbs(app,varargin)
            switchLamp(app,'Plotting ...')
            options = app.options_default;
            if nargin > 1
                if numel(varargin) > 2
                    newOptions = varargin(1:end);
                else
                    newOptions = varargin{1};
                end
                options = updateOptions(options,newOptions);
            end
            ax = app.UIAxes;
            axis(ax,'off')

            if isfield(app.S2Vobj,'nurbs_vol_refined')
                options.color = app.colors;
                h = plotNURBSvec(ax,app.S2Vobj.nurbs_vol_refined,options);
            elseif isfield(app.S2Vobj,'nurbs_vol')
                counter = app.S2Vobj.counter;
                options.color = app.colors(mod(counter-1,app.no_colors)+1,:);
                options.displayName = ['Patch ' num2str(counter)];
                h = plotNURBSvec(ax,app.S2Vobj.nurbs_vol(counter),options);
            else
                h = plotNURBSvec(ax,app.S2Vobj.nurbs_surf,options);
            end
            if ~isa(h.objectHandle(1),'matlab.graphics.GraphicsPlaceholder')
                app.objectHandle = h.objectHandle;
            end
            if ~isa(h.elementEdgesHandle(1),'matlab.graphics.GraphicsPlaceholder')
                app.elementEdgesHandle = h.elementEdgesHandle;
            end
            if ~isa(h.controlPolygonHandle(1),'matlab.graphics.GraphicsPlaceholder')
                app.controlPolygonHandle = h.controlPolygonHandle;
            end
            if ~isa(h.normalVectorsHandle(1),'matlab.graphics.GraphicsPlaceholder')
                app.normalVectorsHandle = h.normalVectorsHandle;
            end
            if ~isa(h.parmDirHandle(1),'matlab.graphics.GraphicsPlaceholder')
                app.parmDirHandle = h.parmDirHandle;
            end
            axis(ax, 'equal')
            if app.CamlightCheckBox.Value && ~(~isempty(app.camlightHandle) && isvalid(app.camlightHandle) && strcmp(app.camlightHandle.Visible,'on'))
                app.camlightHandle = camlight(ax);
            end
        end
        
        function cleanUpPlots(app)
            delete(app.camlightHandle)
            delete(app.objectHandle)
            delete(app.elementEdgesHandle)
            delete(app.controlPolygonHandle)
            delete(app.normalVectorsHandle)
            delete(app.parmDirHandle)
            cla(app.UIAxes)
        end
        
        function switchLamp(app,text)
            if nargin < 2
                app.ReadyLamp.Color = 'green';
                app.ReadyLampLabel.Text = 'Ready';
            else
                app.ReadyLamp.Color = 'red';
                app.ReadyLampLabel.Text = text;
            end
            pause(0.01)            
        end
        
        function options = getOptions(app)
            options = struct('plotObject',app.ShowobjectCheckBox.Value,...
                                'plotElementEdges',app.ShowelementedgesCheckBox.Value,...
                                'plotControlPolygon',app.ShowcontrolpolygonCheckBox.Value,...
                                'plotNormalVectors',app.ShownormalvectorsCheckBox.Value,...
                                'plotParmDir',app.ShowparametricdirectionsCheckBox.Value,...
                                'plotColorFun',app.PlotColorFunCheckBox.Value,...
                                'coarseLinearSampling',app.coarseLinearSamplingCheckBox.Value,...
                                'alphaValue',app.AlphavalueSlider.Value,...
                                'quiverScale',app.QuiverscaleEditField.Value,...
                                'qstep',app.QuiverstepEditField.Value,...
                                'quiverAutoScale',app.AutoscaleCheckBox.Value,...
                                'color',app.plotColor,...
                                'colorFun', str2func(app.ColorfunctionEditField.Value), ...
                                'resolution',app.PlottingresolutionEditField.Value*ones(1,3));
        end
        
        function processNURBSinput(app)
            app.S2Vobj.nurbs_surf = cleanNURBS(app.S2Vobj.nurbs_surf,[],app.Eps);
            if app.S2Vobj.nurbs_surf{1}.d_p == 3
                app.S2Vobj.nurbs_surf = extractFreeSurface(app.S2Vobj.nurbs_surf);
                app.no_colors = max(8,numel(app.S2Vobj.nurbs_surf)/10);
            elseif ~(app.S2Vobj.nurbs_surf{1}.d_p == 2)
                error('Nonsensical input')
            end
            if isempty(app.colors)
                app.no_colors = max(8,numel(app.S2Vobj.nurbs_surf)/10);
                app.colors = getColorMap('shsv',app.UIAxes,app.no_colors);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set default values
            app.Eps = 1e-6;
            app.no_colors = 8;
            app.colors = getColorMap('shsv',app.UIAxes,app.no_colors);
            app.plotColor = getColor();
            app.pauseAutomation = true;

            app.sharpAngle = app.AnglethresholdEditField.Value*pi/180;
            app.options_default = getOptions(app);

            % Set tool tip
            app.coarseLinearSamplingCheckBox.Tooltip = 'Evaluate every element only on the boundary in linear parametric directions';
            app.ShowobjectCheckBox.Tooltip = 'Visualize the patches of the NURBS object';
            app.ShownormalvectorsCheckBox.Tooltip = 'Visualize the normal vectors on the surface of the NURBS object';
            app.ShowparametricdirectionsCheckBox.Tooltip = 'Visualize the parametric directions at the  surface of the NURBS object';
            app.ShowelementedgesCheckBox.Tooltip = 'Visualize the elements of the NURBS object (shows also the interior elements of a NURBS patch)';
            app.ShowcontrolpolygonCheckBox.Tooltip = 'Visualize the full control polygon for the NURBS object';
            app.PlottingresolutionEditField.Tooltip = 'Edit the plotting resolution for the NURBS object. This number is scaled with the ratio between number of C0-elements and the total number of elements';
            app.PlottingresolutionEditFieldLabel.Tooltip = app.PlottingresolutionEditField.Tooltip;
            app.CamlightCheckBox.Tooltip = 'Turn on or off the camlight';
            app.ThicknessEditField.Tooltip = 'Specify the thickness of the patch away from a single surface patch';
            app.AnglethresholdEditField.Tooltip = 'Set the threshold angle between two patches for which the algorithm will try to form a patch between these surface patches';
            app.Play.Tooltip = 'Run/pause automated surface to volume patch generator';
            app.Loadg2fileButton.Tooltip = 'Open a .g2-file from your file system. For linux users: The app window might move down some layers';
            app.ModelDropDown.Tooltip = 'Select a predefined model that loads a NURBS geometry and a predefined set of parameters for the surface2volume algorithm';
            app.PopoutButton.Tooltip = 'Pop out figure to a separate window to enable plot browser/inspector (i.e. "View" -> "Plot Browser" is very convenient in order to hide/edit objects)';
            app.ReadyLamp.Tooltip = 'The lamp turns red when program is busy';
            app.QuiverstepEditField.Tooltip = 'Step over evaluation points when plotting any normal vectors/parametric directions. Setting quiver step = 1 will plot vectors at every evaluation points.';
            app.QuiverstepEditFieldLabel.Tooltip = app.QuiverstepEditField.Tooltip;
            app.QuiverscaleEditField.Tooltip = 'Scale the length of the quiver vectors used for normal vectors/parametric directions.';
            app.QuiverscaleEditFieldLabel.Tooltip = app.QuiverscaleEditField.Tooltip;
            app.AutoscaleCheckBox.Tooltip = 'Scale the length of the quiver vectors automatically using MATLAB built in algorithm.';
            app.ColorfunctionEditField.Tooltip = 'Write a MATLAB function handle for coloring the NURBS object. This is convenient to show the geometric error, i.e., the exactness of the sphere representation using NURBS, which is the default expression.';
            app.AlphavalueEditField.Tooltip = 'Alter the transparancy of the object by changing the alpha value';
            app.AlphavalueSlider.Tooltip = app.AlphavalueEditField.Tooltip;
            app.AlphavalueSliderLabel.Tooltip = app.AlphavalueEditField.Tooltip;
            app.PlotColorFunCheckBox.Tooltip = 'Toggle visualization of the color function.';
            app.ReadyLampLabel.Tooltip = 'Status of app';
            app.Seth_maxEditField.Tooltip = 'Set the maximal (up to rounding) element edge length for all elements throughout the mesh for the refinement procedure';
            app.Seth_maxEditFieldLabel.Tooltip = app.Seth_maxEditField.Tooltip;
            app.RefineButton.Tooltip = 'Refine the volumetric mesh resulting from the S2V algorithm';
            app.DropDown.Tooltip = 'Select NURBS to export';
            app.Exportg2fileButton.Tooltip = 'Export selected NURBS to a file in your file system.';
            
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            switchLamp(app,'Loading model ...')
            app.S2Vobj = [];
            model = app.ModelDropDown.Value;            
            switch model
                case 'Mutter'
                    nurbs_input = read_g2('../IGA-geometries/Mutter/Mutter.g2');
                    nurbs_input = cleanNURBS(nurbs_input,[],1e-2);
                    app.S2Vobj.nurbs_surf  = extractFreeSurface(nurbs_input);
            
                    app.ThicknessEditField.Value = 1.1;
                    app.AnglethresholdEditField.Value = 120; % Threshold for a "sharp" angle
                case 'Capablanca'
                    load('../../results/ASIGA/Capablanca/nurbs.mat','nurbs')
                    app.S2Vobj.nurbs_surf = nurbs;
            
                    app.ThicknessEditField.Value = 1.1;
                    app.AnglethresholdEditField.Value = 120; % Threshold for a "sharp" angle
                case 'Capablanca_woRooks'
                    load('../../results/ASIGA/Capablanca/nurbs_woRook.mat','nurbs')
                    app.S2Vobj.nurbs_surf = nurbs;
                    app.ThicknessEditField.Value = 1.1;
                    app.AnglethresholdEditField.Value = 120; % Threshold for a "sharp" angle
                case 'BCA'
                    load('BeTSSi_BCA_p2_unRefined.mat','nurbs')
                    app.S2Vobj.nurbs_surf = nurbs;
                    app.ThicknessEditField.Value = 1.1;
                    % sharpAngle = 100*pi/180; % Threshold for a "sharp" angle
                    app.AnglethresholdEditField.Value = 120; % Threshold for a "sharp" angle
                    % sharpAngle = 125*pi/180; % Threshold for a "sharp" angle
                    % sharpAngle = 135*pi/180; % Threshold for a "sharp" angle
                    % sharpAngle = 160*pi/180; % Threshold for a "sharp" angle
                    % sharpAngle = 161*pi/180; % Threshold for a "sharp" angle % In order to include connections over depth rudders
                    % sharpAngle = 173*pi/180; % Threshold for a "sharp" angle
                case 'S1_interior'
                    app.S2Vobj.nurbs_surf = getEllipsoidData('parm',2,'S2V_algorithm',{'A1_2'});
                    app.S2Vobj.nurbs_surf = flipNURBSparametrization(app.S2Vobj.nurbs_surf,1);
                    app.AnglethresholdEditField.Value = 140; % Threshold for a "sharp" angle
                case 'S1'
                    app.S2Vobj.nurbs_surf = getEllipsoidData('parm',1);
                    app.AnglethresholdEditField.Value = 140; % Threshold for a "sharp" angle
                otherwise
                    if model(1) == 'M'
                        app.ThicknessEditField.Value = 1.5;
                    end
                    app.S2Vobj.nurbs_surf = read_g2(['NURBSgeometries/g2files/' model '.g2']);
                    app.AnglethresholdEditField.Value = 140; % Threshold for a "sharp" angle
            end
            processNURBSinput(app)
            cleanUpPlots(app)
            plot_nurbs(app,getOptions(app))
            view(app.UIAxes,getView());
            switchLamp(app)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: AnglethresholdEditField
        function AnglethresholdEditFieldValueChanged(app, event)
            app.sharpAngle = app.AnglethresholdEditField.Value*pi/180;
        end

        % Value changed function: ThicknessEditField
        function ThicknessEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: Play
        function PlayPushed(app, event)
            switchLamp(app,'Running S2V algorithm ...')
            app.pauseAutomation = ~app.pauseAutomation;

            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Play.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'pause.svg');
            if isempty(app.S2Vobj)
                return
            else
                while (~isfield(app.S2Vobj,'S2Vcompleted')  || ~app.S2Vobj.S2Vcompleted) && ~app.pauseAutomation
                    app.S2Vobj = surfaceToVolume(app.S2Vobj,'t',app.ThicknessEditField.Value,'sharpAngle',app.sharpAngle,'ax',app.UIAxes);
                    plot_nurbs(app,getOptions(app))
                end
            end
            app.Play.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'play.svg');
            switchLamp(app)
        end

        % Button pushed function: Loadg2fileButton
        function Loadg2fileButtonPushed(app, event)
            switchLamp(app,'Opening file ...')
            [filename, pathname] = uigetfile('*.g2', 'Open a .g2 file','NURBSgeometries/g2files/');
            if filename == -1
                warning(['Could not open file ' filename])
                switchLamp(app)   
                return
            elseif filename == 0
                switchLamp(app)   
                return
            end
            app.S2Vobj = [];
            app.S2Vobj.nurbs_surf = read_g2([pathname, filename]);
            processNURBSinput(app)
            cleanUpPlots(app)
            plot_nurbs(app,getOptions(app));
            view(app.UIAxes,getView());
            switchLamp(app)
        end

        % Value changed function: CamlightCheckBox
        function CamlightCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling camlight ...')
            value = app.CamlightCheckBox.Value;
            if value
                app.camlightHandle = camlight(app.UIAxes);
            else
                if isvalid(app.camlightHandle)
                    app.camlightHandle.Visible = 'off';
                end
            end
            switchLamp(app)
        end

        % Value changed function: PlottingresolutionEditField
        function PlottingresolutionEditFieldValueChanged(app, event)
            switchLamp(app,'Altering plotting resolution ...')
            delete(app.objectHandle)
            delete(app.elementEdgesHandle)
            options = getOptions(app);
            app.PlottingresolutionEditField.Value = max(round(app.PlottingresolutionEditField.Value),0);
            options.resolution = app.PlottingresolutionEditField.Value*ones(1,3);
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Value changed function: ShowcontrolpolygonCheckBox
        function ShowcontrolpolygonCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of control polygon ...')
            if app.ShowcontrolpolygonCheckBox.Value
                if ~isempty(app.controlPolygonHandle) && ~isa(app.controlPolygonHandle(1),'matlab.graphics.GraphicsPlaceholder') && isvalid(app.controlPolygonHandle(1))
                    set(app.controlPolygonHandle,'Visible','on');
                else
                    options = struct('plotObject',false,'plotControlPolygon',app.ShowcontrolpolygonCheckBox.Value,'plotElementEdges',false);
                    plot_nurbs(app,options);
                end
            else
                if isvalid(app.controlPolygonHandle(1))
                    set(app.controlPolygonHandle,'Visible','off');
                end
            end
            switchLamp(app)
        end

        % Value changed function: ShowelementedgesCheckBox
        function ShowelementedgesCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of elements ...')
            if app.ShowelementedgesCheckBox.Value
                if ~isempty(app.elementEdgesHandle) && ~isa(app.elementEdgesHandle(1),'matlab.graphics.GraphicsPlaceholder') && isvalid(app.elementEdgesHandle(1))
                    set(app.elementEdgesHandle,'Visible','on');
                else
                    options = struct('plotObject',false);
                    plot_nurbs(app,options);
                end
            else
                if isvalid(app.elementEdgesHandle(1))
                    set(app.elementEdgesHandle,'Visible','off');
                end
            end
            switchLamp(app)
        end

        % Value changed function: ShowparametricdirectionsCheckBox
        function ShowparametricdirectionsCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of parametric directions ...')
            if app.ShowparametricdirectionsCheckBox.Value
                if ~isempty(app.parmDirHandle) && ~isa(app.parmDirHandle(1),'matlab.graphics.GraphicsPlaceholder') && isvalid(app.parmDirHandle(1))
                    set(app.parmDirHandle,'Visible','on');
                else
                    options = struct('plotObject',false,'plotParmDir',app.ShowparametricdirectionsCheckBox.Value,...
                                        'plotElementEdges',false,...
                                        'quiverScale',app.QuiverscaleEditField.Value,...
                                        'qstep',app.QuiverstepEditField.Value);
                    plot_nurbs(app,options);
                end
            else
                if ~isempty(app.parmDirHandle) && isvalid(app.parmDirHandle(1))
                    set(app.parmDirHandle,'Visible','off');
                end
            end
            switchLamp(app)            
        end

        % Value changed function: ShownormalvectorsCheckBox
        function ShownormalvectorsCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of normal vectors ...')
            if app.ShownormalvectorsCheckBox.Value
                if ~isempty(app.normalVectorsHandle) && ~isa(app.normalVectorsHandle(1),'matlab.graphics.GraphicsPlaceholder') && isvalid(app.normalVectorsHandle(1))
                    set(app.normalVectorsHandle,'Visible','on');
                else
                    options = struct('plotObject',false,'plotNormalVectors',app.ShownormalvectorsCheckBox.Value,...
                                        'plotElementEdges',false,...
                                        'quiverScale',app.QuiverscaleEditField.Value,...
                                        'qstep',app.QuiverstepEditField.Value);
                    plot_nurbs(app,options);
                end
            else
                if ~isempty(app.normalVectorsHandle) && isvalid(app.normalVectorsHandle(1))
                    set(app.normalVectorsHandle,'Visible','off');
                end
            end
            switchLamp(app)            
        end

        % Value changed function: ShowobjectCheckBox
        function ShowobjectCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling visualization of object ...')
            if app.ShowobjectCheckBox.Value
                if ~isempty(app.objectHandle) && ~isa(app.objectHandle(1),'matlab.graphics.GraphicsPlaceholder') && isvalid(app.objectHandle(1))
                    set(app.objectHandle,'Visible','on');
                else
                    options = struct('plotObject',app.ShowobjectCheckBox,'plotElementEdges',false);
                    plot_nurbs(app,options);
                end
            else
                if isvalid(app.objectHandle(1))
                    set(app.objectHandle,'Visible','off');
                end
            end
            switchLamp(app)            
        end

        % Value changed function: coarseLinearSamplingCheckBox
        function coarseLinearSamplingCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling coarse linear sampling of object ...')
            cleanUpPlots(app)
            options = getOptions(app);
            options.coarseLinearSampling = app.coarseLinearSamplingCheckBox.Value;
            plot_nurbs(app,options)
            switchLamp(app)
        end

        % Button pushed function: PopoutButton
        function PopoutButtonPushed(app, event)
            switchLamp(app,'Copying figure onto new figure ...')
            f2 = figure('units','normalized','outerposition',[0 0 1 1]);
            copyobj(app.UIAxes,f2);
            f2.CurrentAxes.Position = [0, 0, 1920, 1080];
            switchLamp(app)
        end

        % Value changing function: AlphavalueSlider
        function AlphavalueSliderValueChanging(app, event)
            switchLamp(app,'Changing alpha value ...')
            if nargin < 2
                value = app.AlphavalueSlider.Value;
            else
                value = event.Value;
                app.AlphavalueEditField.Value = event.Value;
            end
            for patch = 1:numel(app.objectHandle)
                app.objectHandle(patch).FaceAlpha = value;
            end
            switchLamp(app)
        end

        % Value changed function: ColorfunctionEditField
        function ColorfunctionEditFieldValueChanged(app, event)
            switchLamp(app,'Changing color function...')
            delete(app.objectHandle)
            options = getOptions(app);
            options.plotElementEdges = false;
            options.plotControlPolygon = false;
            options.plotParmDir = false;
            options.colorFun = str2func(app.ColorfunctionEditField.Value);
            plot_nurbs(app,options)
            colorbar(app.UIAxes)
            switchLamp(app)
        end

        % Value changed function: PlotColorFunCheckBox
        function PlotColorFunCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling color function...')
            if app.PlotColorFunCheckBox.Value
                app.coarseLinearSamplingCheckBox.Value = false;
                ColorfunctionEditFieldValueChanged(app, event);
            else
                colorbar(app.UIAxes, 'off')
                options = getOptions(app);
                for patch = 1:numel(app.objectHandle)
                    app.objectHandle(patch).FaceColor = options.color;
                end
            end
            switchLamp(app)
        end

        % Value changed function: AlphavalueEditField
        function AlphavalueEditFieldValueChanged(app, event)
            switchLamp(app,'Changing alpha value ...')
            value = app.AlphavalueEditField.Value;
            app.AlphavalueEditField.Value = value;
            app.AlphavalueSlider.Value = value;
            AlphavalueSliderValueChanging(app)
            switchLamp(app)
        end

        % Value changed function: QuiverscaleEditField
        function QuiverscaleEditFieldValueChanged(app, event)
            switchLamp(app,'Editing quiver scale ...')
            value = app.QuiverscaleEditField.Value;
            if app.AutoscaleCheckBox.Value
                for patch = 1:numel(app.normalVectorsHandle)
                    app.normalVectorsHandle(patch).AutoScale = 'on';
                    app.normalVectorsHandle(patch).AutoScaleFactor = value;
                end
                for patch = 1:size(app.parmDirHandle,2)
                    for i = 1:size(app.parmDirHandle,1)
                        app.parmDirHandle(i,patch).AutoScale = 'on';
                        app.parmDirHandle(i,patch).AutoScaleFactor = value;
                    end
                end
            else
                delete(app.normalVectorsHandle)
                delete(app.parmDirHandle)
                options = getOptions(app);
                options.plotElementEdges = false;
                options.plotControlPolygon = false;
                options.plotObject = false;
                plot_nurbs(app,options)
            end
            switchLamp(app)
        end

        % Value changed function: QuiverstepEditField
        function QuiverstepEditFieldValueChanged(app, event)
            switchLamp(app,'Editing quiver scale ...')
            delete(app.normalVectorsHandle)
            delete(app.parmDirHandle)
            ShownormalvectorsCheckBoxValueChanged(app)
            ShowparametricdirectionsCheckBoxValueChanged(app)
            switchLamp(app)
        end

        % Button pushed function: RefineButton
        function RefineButtonPushed(app, event)
            switchLamp(app,'Refining volumetric NURBS ...')
            if isempty(app.S2Vobj.nurbs_vol)
                warning('Could not find any volumetric NURBS. Did you run the S2V algorithm?')
            end
            if isfield(app.S2Vobj,'nurbs_vol_connection')
                connection = app.S2Vobj.nurbs_vol_connection;
            else
                geometry = getTopology(app.S2Vobj.nurbs_vol);
                connection = geometry.topology.connection;
                app.S2Vobj.nurbs_vol_connection = connection;
            end
            app.S2Vobj.nurbs_vol_refined = autoRefineNURBS(app.S2Vobj.nurbs_vol,connection,app.Seth_maxEditField.Value);
            cleanUpPlots(app)
            plot_nurbs(app,getOptions(app))
            switchLamp(app)
        end

        % Button pushed function: Exportg2fileButton
        function Exportg2fileButtonPushed(app, event)
            switchLamp(app,'Opening file ...')
            [filename, pathname] = uiputfile('*.g2', 'Save a .g2 file','NURBSgeometries/g2files/');
            if filename == -1
                warning(['Could not open file ' filename])
                switchLamp(app)   
                return
            elseif filename == 0
                switchLamp(app)   
                return
            end
            switch app.DropDown.Value
                case 'S2V result'
                    write_g2(app.S2Vobj.nurbs_vol, [pathname, filename]);
                case 'Refined S2V mesh'
                    write_g2(app.S2Vobj.nurbs_vol_refined, [pathname, filename]);
            end
            switchLamp(app)   
        end

        % Value changed function: AutoscaleCheckBox
        function AutoscaleCheckBoxValueChanged(app, event)
            switchLamp(app,'Toggling autoscale for quiver plots ...')
            delete(app.normalVectorsHandle)
            delete(app.parmDirHandle)
            ShownormalvectorsCheckBoxValueChanged(app)
            ShowparametricdirectionsCheckBoxValueChanged(app)
            switchLamp(app)
            
        end

        % Button pushed function: reset
        function resetPushed(app, event)
            switchLamp(app,'Cleaning up plots and reloading the model ...')
            cleanUpPlots(app)
            nurbs_surf = app.S2Vobj.nurbs_surf;
            app.S2Vobj = [];
            app.S2Vobj.nurbs_surf = nurbs_surf;
            app.nurbs_vol_connection = [];
            app.nurbs_vol_refined = [];
            plot_nurbs(app,getOptions(app))
            view(app.UIAxes,getView());
            switchLamp(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Surface2volumealgorithmUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {687, 687};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {305, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Surface2volumealgorithmUIFigure = uifigure('Visible', 'off');
            colormap(app.Surface2volumealgorithmUIFigure, 'parula');
            app.Surface2volumealgorithmUIFigure.Name = 'Surface2volume algorithm';
            app.Surface2volumealgorithmUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Surface2volumealgorithmUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModelDropDownLabel = uilabel(app.LeftPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.Surface2volumealgorithmLabel = uilabel(app.LeftPanel);
            app.Surface2volumealgorithmLabel.Text = 'Surface2volume algorithm';
            app.Loadg2fileButton = uibutton(app.LeftPanel, 'push');
            app.Loadg2fileButton.ButtonPushedFcn = createCallbackFcn(app, @Loadg2fileButtonPushed, true);
            app.Loadg2fileButton.Text = 'Load g2-file';
            app.ModelDropDown = uidropdown(app.LeftPanel);
            app.ModelDropDown.Items = {'<Select model>', 'Barrel', 'BCA', 'M1', 'M2', 'M3', 'M4', 'M5', 'S1', 'S1_interior', 'Cube'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = '<Select model>';
            app.ORLabel = uilabel(app.LeftPanel);
            app.ORLabel.Text = 'OR';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.S2VTab = uitab(app.TabGroup);
            app.S2VTab.Title = 'S2V';
            app.Surface2volumesettingsPanel = uipanel(app.S2VTab);
            app.Surface2volumesettingsPanel.Title = 'Surface2volume settings';
            app.AnglethresholdEditFieldLabel = uilabel(app.Surface2volumesettingsPanel);
            app.AnglethresholdEditFieldLabel.Text = 'Angle threshold';
            app.AnglethresholdEditField = uieditfield(app.Surface2volumesettingsPanel, 'numeric');
            app.AnglethresholdEditField.ValueChangedFcn = createCallbackFcn(app, @AnglethresholdEditFieldValueChanged, true);
            app.AnglethresholdEditField.Value = 130;
            app.ThicknessEditFieldLabel = uilabel(app.Surface2volumesettingsPanel);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.Surface2volumesettingsPanel, 'numeric');
            app.ThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessEditFieldValueChanged, true);
            app.ThicknessEditField.Value = 0.1;
            app.Play = uibutton(app.S2VTab, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayPushed, true);
            app.Play.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'play.svg');
            app.Play.Text = '';
            app.Forward = uibutton(app.S2VTab, 'push');
            app.Forward.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'forward.svg');
            app.Forward.Text = '';
            app.Backward = uibutton(app.S2VTab, 'push');
            app.Backward.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'backward.svg');
            app.Backward.Text = '';
            app.reset = uibutton(app.S2VTab, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetPushed, true);
            app.reset.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'backwardEnd.svg');
            app.reset.Text = '';
            app.Button = uibutton(app.S2VTab, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'miscellaneous', 'icons', 'forwardEnd.svg');
            app.Button.Text = '';
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.SettingsforplottingPanel = uipanel(app.PlottingTab);
            app.SettingsforplottingPanel.Title = 'Settings for plotting';
            app.CamlightCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.CamlightCheckBox.ValueChangedFcn = createCallbackFcn(app, @CamlightCheckBoxValueChanged, true);
            app.CamlightCheckBox.Text = 'Camlight';
            app.CamlightCheckBox.Value = true;
            app.PlottingresolutionEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.PlottingresolutionEditFieldLabel.Text = 'Plotting resolution';
            app.PlottingresolutionEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.PlottingresolutionEditField.Limits = [0 400];
            app.PlottingresolutionEditField.RoundFractionalValues = 'on';
            app.PlottingresolutionEditField.ValueChangedFcn = createCallbackFcn(app, @PlottingresolutionEditFieldValueChanged, true);
            app.PlottingresolutionEditField.Value = 32;
            app.ShowcontrolpolygonCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowcontrolpolygonCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowcontrolpolygonCheckBoxValueChanged, true);
            app.ShowcontrolpolygonCheckBox.Text = 'Show control polygon';
            app.ShowelementedgesCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowelementedgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowelementedgesCheckBoxValueChanged, true);
            app.ShowelementedgesCheckBox.Text = 'Show element edges';
            app.ShowelementedgesCheckBox.Value = true;
            app.ShowparametricdirectionsCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowparametricdirectionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowparametricdirectionsCheckBoxValueChanged, true);
            app.ShowparametricdirectionsCheckBox.Text = 'Show parametric directions';
            app.ShownormalvectorsCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShownormalvectorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShownormalvectorsCheckBoxValueChanged, true);
            app.ShownormalvectorsCheckBox.Text = 'Show normal vectors';
            app.ShowobjectCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowobjectCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowobjectCheckBoxValueChanged, true);
            app.ShowobjectCheckBox.Text = 'Show object';
            app.ShowobjectCheckBox.Value = true;
            app.coarseLinearSamplingCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.coarseLinearSamplingCheckBox.ValueChangedFcn = createCallbackFcn(app, @coarseLinearSamplingCheckBoxValueChanged, true);
            app.coarseLinearSamplingCheckBox.Text = 'Use two-point sampling for linear directions';
            app.coarseLinearSamplingCheckBox.Value = true;
            app.AlphavalueSliderLabel = uilabel(app.SettingsforplottingPanel);
            app.AlphavalueSliderLabel.Text = 'Alpha value';
            app.AlphavalueSlider = uislider(app.SettingsforplottingPanel);
            app.AlphavalueSlider.Limits = [0 1];
            app.AlphavalueSlider.MajorTicks = [];
            app.AlphavalueSlider.MajorTickLabels = {''};
            app.AlphavalueSlider.ValueChangingFcn = createCallbackFcn(app, @AlphavalueSliderValueChanging, true);
            app.AlphavalueSlider.MinorTicks = [];
            app.AlphavalueSlider.Value = 1;
            app.ColorfunctionEditField = uieditfield(app.SettingsforplottingPanel, 'text');
            app.ColorfunctionEditField.ValueChangedFcn = createCallbackFcn(app, @ColorfunctionEditFieldValueChanged, true);
            app.ColorfunctionEditField.Value = '@(x) log10(abs(norm2(x(:,1:3))-1))';
            app.PlotColorFunCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.PlotColorFunCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotColorFunCheckBoxValueChanged, true);
            app.PlotColorFunCheckBox.Text = 'Color function:';
            app.AlphavalueEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.AlphavalueEditField.Limits = [0 1];
            app.AlphavalueEditField.ValueChangedFcn = createCallbackFcn(app, @AlphavalueEditFieldValueChanged, true);
            app.QuiverscaleEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.QuiverscaleEditFieldLabel.Text = 'Quiver scale';
            app.QuiverscaleEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.QuiverscaleEditField.Limits = [0 10];
            app.QuiverscaleEditField.ValueChangedFcn = createCallbackFcn(app, @QuiverscaleEditFieldValueChanged, true);
            app.QuiverscaleEditField.Value = 0.5;
            app.QuiverstepEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.QuiverstepEditFieldLabel.Text = 'Quiver step';
            app.QuiverstepEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.QuiverstepEditField.Limits = [1 Inf];
            app.QuiverstepEditField.ValueChangedFcn = createCallbackFcn(app, @QuiverstepEditFieldValueChanged, true);
            app.QuiverstepEditField.Value = 10;
            app.AutoscaleCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.AutoscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoscaleCheckBoxValueChanged, true);
            app.AutoscaleCheckBox.Text = 'Autoscale';
            app.PostprocessingTab = uitab(app.TabGroup);
            app.PostprocessingTab.Title = 'Post processing';
            app.ExportPanel = uipanel(app.PostprocessingTab);
            app.ExportPanel.Title = 'Export';
            app.Exportg2fileButton = uibutton(app.ExportPanel, 'push');
            app.Exportg2fileButton.ButtonPushedFcn = createCallbackFcn(app, @Exportg2fileButtonPushed, true);
            app.Exportg2fileButton.Text = 'Export g2-file';
            app.DropDown = uidropdown(app.ExportPanel);
            app.DropDown.Items = {'S2V result', 'Refined S2V mesh'};
            app.DropDown.Value = 'S2V result';
            app.RefinementPanel = uipanel(app.PostprocessingTab);
            app.RefinementPanel.Title = 'Refinement';
            app.andLabel = uilabel(app.RefinementPanel);
            app.andLabel.Text = 'and';
            app.RefineButton = uibutton(app.RefinementPanel, 'push');
            app.RefineButton.ButtonPushedFcn = createCallbackFcn(app, @RefineButtonPushed, true);
            app.RefineButton.Text = 'Refine';
            app.Seth_maxEditFieldLabel = uilabel(app.RefinementPanel);
            app.Seth_maxEditFieldLabel.Text = 'Set h_max';
            app.Seth_maxEditField = uieditfield(app.RefinementPanel, 'numeric');
            app.Seth_maxEditField.Limits = [0 Inf];
            app.Seth_maxEditField.Value = 0.3;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ReadyLampLabel = uilabel(app.RightPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.RightPanel);
            app.PopoutButton = uibutton(app.RightPanel, 'push');
            app.PopoutButton.ButtonPushedFcn = createCallbackFcn(app, @PopoutButtonPushed, true);
            app.PopoutButton.Text = 'Pop out';
            % Show the figure after all components are created
            app.Surface2volumealgorithmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = surface2volume

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Surface2volumealgorithmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Surface2volumealgorithmUIFigure)
        end
    end
end